classdef proyecto_metodos_numericos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TrazadorCbicoNaturalTab       matlab.ui.container.Tab
        TrazadorCbicoNaturalPanel     matlab.ui.container.Panel
        LimpiarButton                 matlab.ui.control.Button
        PruebaButton                  matlab.ui.control.Button
        CalcularButton                matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        CantidaddePuntosSpinner       matlab.ui.control.Spinner
        CantidaddePuntosSpinnerLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        MtodoIterativoJacobiTab       matlab.ui.container.Tab
        MtodoIterativoJacobiPanel     matlab.ui.container.Panel
        MtododelaPotenciaTab          matlab.ui.container.Tab
        MtododelaPotenciaPanel        matlab.ui.container.Panel
    end

    %Method of TrazadorCubico
    methods (Access = private)
        
        function results = TrazadorCubico(app, n, x, y)
            
            % Step 1, calcule h values
            h = zeros(n-1, 1);
            
            for i = 1:n-1
                h(i) = x(i+1) - x(i);
            end
            
            % Step 2, Calculate a values
            a = zeros(n-1,1);
            
            for i = 2:n-1                
                a(i) = (3/h(i))*(y(i+1)-y(i))-(3/h(i-1))*(y(i)-y(i-1));
            end            
            
            % Step 3, Define l,u,z
            l = zeros(n,1);
            u = zeros(n,1);
            z = zeros(n,1);
            
            l(1) = 1;
            u(1) = 0;
            z(1) = 0;
            
            % Step 4, Calculate l,u,z values

            for i = 2:n-1                
                l(i) = 2*(x(i+1)-x(i-1))-h(i-1)*u(i-1);
                u(i) = h(i)/l(i);
                z(i) = (a(i)-h(i-1)*z(i-1))/l(i);
            end
            
            % Step 5, Define l,u,z in n
            l(n) = 1;
            u(n) = 0;
            z(n) = 0;
            
            % Step 6, Calculate c,b,d
            c = zeros(n,1);
            b = zeros(n,1);
            d = zeros(n,1);
                      
            for j=n-1:-1:1
                c(j) = z(j)-u(j)*c(j+1);
                b(j) = (y(j+1)-y(j))/h(j)-(h(j)*(c(j+1)+2*c(j)))/3;
                d(j) = (c(j+1)-c(j))/3*h(j);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CantidaddePuntosSpinner, 
        % TrazadorCbicoNaturalPanel
        function TrazadorCbicoNaturalPanelSizeChanged(app, event)
            numberOfEntries = event.Value;  
            currentTableData = app.UITable.Data;            
            if(size(currentTableData) > 0)
                if(numberOfEntries > event.PreviousValue)
                    newRow = [0 0];
                    newTableData = [currentTableData; newRow];
                    app.UITable.Data = newTableData;
                else                    
                    currentTableData(end,:) = [];
                    app.UITable.Data = currentTableData;
                end
            else
                app.UITable.Data = zeros(numberOfEntries, 2);
            end    
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;             
            index = indices(1,1);
            
            if(index > 1)
                previusRowIndex = index-1;
                previusRowData = app.UITable.Data(previusRowIndex, 1);
                if(previusRowData >= newData)
                    uialert(app.UIFigure,'El valor ingresado debe ser mayor al anterior del eje x!','Valor incorrecto',...
                    'Icon','error');
                    app.UITable.Data(index, 1) = event.PreviousData;
                end                
            end            
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            n = app.CantidaddePuntosSpinner.Value;
            currentTableData = app.UITable.Data;           
            
            % Read x values
            x = zeros(n,1);
            
            for c = 1:n
                x(c) = currentTableData(c,1);
            end
            
            % Read y values
            y = zeros(n,1);
            
            for c = 1:n
                y(c) = currentTableData(c,2);
            end
            
            app.TrazadorCubico(n, x, y)
            
        end

        % Button pushed function: PruebaButton
        function PruebaButtonPushed(app, event)
            app.UITable.Data = [0.1 1.45; 0.2 1.8; 0.3 1.7; 0.4 2.0];
            app.CantidaddePuntosSpinner.Value = 4;
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.UITable.Data = [];
            app.CantidaddePuntosSpinner.Value = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrazadorCbicoNaturalTab = uitab(app.TabGroup);
            app.TrazadorCbicoNaturalTab.Title = 'Trazador Cúbico Natural';
            app.TrazadorCbicoNaturalPanel = uipanel(app.TrazadorCbicoNaturalTab);
            app.TrazadorCbicoNaturalPanel.Title = 'Trazador Cúbico Natural';
            app.TrazadorCbicoNaturalPanel.SizeChangedFcn = createCallbackFcn(app, @TrazadorCbicoNaturalPanelSizeChanged, true);
            app.UIAxes = uiaxes(app.TrazadorCbicoNaturalPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CantidaddePuntosSpinnerLabel = uilabel(app.TrazadorCbicoNaturalPanel);
            app.CantidaddePuntosSpinnerLabel.Text = 'Cantidad de Puntos:';
            app.CantidaddePuntosSpinner = uispinner(app.TrazadorCbicoNaturalPanel);
            app.CantidaddePuntosSpinner.Limits = [1 Inf];
            app.CantidaddePuntosSpinner.ValueChangedFcn = createCallbackFcn(app, @TrazadorCbicoNaturalPanelSizeChanged, true);
            app.CantidaddePuntosSpinner.Value = 1;
            app.UITable = uitable(app.TrazadorCbicoNaturalPanel);
            app.UITable.ColumnName = {'Xi'; 'Yi'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CalcularButton = uibutton(app.TrazadorCbicoNaturalPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.PruebaButton = uibutton(app.TrazadorCbicoNaturalPanel, 'push');
            app.PruebaButton.ButtonPushedFcn = createCallbackFcn(app, @PruebaButtonPushed, true);
            app.PruebaButton.Text = 'Prueba';
            app.LimpiarButton = uibutton(app.TrazadorCbicoNaturalPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.MtodoIterativoJacobiTab = uitab(app.TabGroup);
            app.MtodoIterativoJacobiTab.Title = 'Método Iterativo Jacobi';
            app.MtodoIterativoJacobiPanel = uipanel(app.MtodoIterativoJacobiTab);
            app.MtodoIterativoJacobiPanel.Title = 'Método Iterativo Jacobi';
            app.MtododelaPotenciaTab = uitab(app.TabGroup);
            app.MtododelaPotenciaTab.Title = 'Método de la Potencia';
            app.MtododelaPotenciaPanel = uipanel(app.MtododelaPotenciaTab);
            app.MtododelaPotenciaPanel.Title = 'Método de la Potencia';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyecto_metodos_numericos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end